classdef circuit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        Menu_2              matlab.ui.container.Menu
        Menu_3              matlab.ui.container.Menu
        Panel               matlab.ui.container.Panel
        ButtonGroup         matlab.ui.container.ButtonGroup
        Button              matlab.ui.control.ToggleButton
        Button_2            matlab.ui.control.ToggleButton
        Button_3            matlab.ui.control.ToggleButton
        Button_4            matlab.ui.control.ToggleButton
        Button_6            matlab.ui.control.ToggleButton
        TabGroup            matlab.ui.container.TabGroup
        RTab                matlab.ui.container.Tab
        Label_2             matlab.ui.control.Label
        EditField_2         matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        EditField           matlab.ui.control.NumericEditField
        RLabel              matlab.ui.control.Label
        REditField          matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        GR1RLabel           matlab.ui.control.Label
        CTab                matlab.ui.container.Tab
        EditField_3Label    matlab.ui.control.Label
        EditField_3         matlab.ui.control.NumericEditField
        EditField_4Label    matlab.ui.control.Label
        EditField_4         matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        CEditFieldLabel     matlab.ui.control.Label
        CEditField          matlab.ui.control.NumericEditField
        GCjCLabel           matlab.ui.control.Label
        LTab                matlab.ui.container.Tab
        EditField_5Label    matlab.ui.control.Label
        EditField_5         matlab.ui.control.NumericEditField
        EditField_6Label    matlab.ui.control.Label
        EditField_6         matlab.ui.control.NumericEditField
        Label_5             matlab.ui.control.Label
        LEditFieldLabel     matlab.ui.control.Label
        LEditField          matlab.ui.control.NumericEditField
        GLjLLabel           matlab.ui.control.Label
        VTab                matlab.ui.container.Tab
        EditField_7Label    matlab.ui.control.Label
        EditField_7         matlab.ui.control.NumericEditField
        EditField_8Label    matlab.ui.control.Label
        EditField_8         matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        USEditFieldLabel    matlab.ui.control.Label
        USEditField         matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        Label_8             matlab.ui.control.Label
        EditField_9         matlab.ui.control.NumericEditField
        USUScosjUSsinLabel  matlab.ui.control.Label
        GTab                matlab.ui.container.Tab
        EditField_10Label   matlab.ui.control.Label
        EditField_10        matlab.ui.control.NumericEditField
        Button_5            matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        Panel_2             matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        Button_7            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        TextArea2           matlab.ui.control.TextArea
        Panel_3             matlab.ui.container.Panel
        radsKnobLabel       matlab.ui.control.Label
        radsKnob            matlab.ui.control.Knob
        radsLabel           matlab.ui.control.Label
        EditField_11Label   matlab.ui.control.Label
        EditField_11        matlab.ui.control.NumericEditField
        TextAreaLabel       matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        function results = getData(app,text)
            switch text
                case "电阻"
                    name="电阻";
                    node1=app.EditField.Value;
                    node2=app.EditField_2.Value;
                    value1=app.REditField.Value;
                    value2=-1;
                        
                case "电容"
                    name="电容";
                    node1=app.EditField_4.Value;
                    node2=app.EditField_3.Value;
                    value1=app.CEditField.Value;
                    value2=-1;
                    
                case "电感"
                    name="电感";
                    node1=app.EditField_6.Value;
                    node2=app.EditField_5.Value;
                    value1=app.LEditField.Value;
                    value2=-1;
                    
                case "电压源"
                    name="电压源";
                    node1=app.EditField_8.Value;
                    node2=app.EditField_7.Value;
                    value1=app.USEditField.Value;
                    value2=app.EditField_9.Value;
                    
                otherwise
                    name="地线";
                    node1=app.EditField_10.Value;
                    node2=-1;
                    value1=-1;
                    value2=-1;
                    
            end
            
            results=[name,node1,node2,value1,value2];
        end
        
        
        function results = nodeVoltage(app,data)
            global nodeNum;
            AA = zeros(nodeNum+1);
            bb = zeros(nodeNum+1,1);      
            %默认未知数为1
            VSNum=1; 
            %nodeNum 等于矩阵维数
            nodeNums = nodeNum + 1;
            % 地线
            jiedi=0;
            jiedistr=strfind(data,"地线");
            for j=1:size(jiedistr,1)
                a=isempty(jiedistr{j,1});
                if a==0
                    jiedi=jiedi+1;
                end
            end
            cpNum=size(data,1)-jiedi; 
            % omega
            omega=app.EditField_11.Value;

            for i=1:cpNum
                cpName=string(data(i,1));
                cpNo1=int8(str2double(data(i,2))+1);
                cpNo2=int8(str2double(data(i,3))+1);    
                cpValue1=str2double(data(i,4))+1;
                cpValue2=str2double(data(i,5))+1;              

                switch char(cpName)           
                    case '电压源'
                        cpValue=complex(cpValue1.*cosd(cpValue2),cpValue1.*sind(cpValue2));
                        %扩展矩阵
                        AA(nodeNums+VSNum,nodeNums+VSNum)= 0;
                        bb(nodeNums+VSNum)=0;
                        
                        AA(cpNo1,nodeNums+VSNum)= AA(cpNo1,nodeNums+VSNum) + 1;
                        AA(cpNo2,nodeNums+VSNum)= AA(cpNo2,nodeNums+VSNum) - 1;
                        AA(nodeNums+VSNum,cpNo1)= AA(nodeNums+VSNum,cpNo1) + 1;
                        AA(nodeNums+VSNum,cpNo2)= AA(nodeNums+VSNum,cpNo2) - 1;
                        bb(nodeNums+VSNum)=bb(nodeNums+VSNum)+cpValue;
                        VSNum=VSNum+1;
                    case '电阻'
                        cpValue=complex(1/cpValue1,0);
                        AA(cpNo1,cpNo1)= AA(cpNo1,cpNo1)+cpValue;
                        AA(cpNo1,cpNo2)= AA(cpNo1,cpNo2)-cpValue;
                        AA(cpNo2,cpNo1)= AA(cpNo2,cpNo1)-cpValue;
                        AA(cpNo2,cpNo2)= AA(cpNo2,cpNo2)+cpValue;
                    case '电容'
                        cpValue=complex(0,omega.*cpValue1);
                        AA(cpNo1,cpNo1)= AA(cpNo1,cpNo1)+cpValue;
                        AA(cpNo1,cpNo2)= AA(cpNo1,cpNo2)-cpValue;
                        AA(cpNo2,cpNo1)= AA(cpNo2,cpNo1)-cpValue;
                        AA(cpNo2,cpNo2)= AA(cpNo2,cpNo2)+cpValue;
                    case '电感'
                        cpValue=complex(0,-1/(omega.*cpValue1));
                        AA(cpNo1,cpNo1)= AA(cpNo1,cpNo1)+cpValue;
                        AA(cpNo1,cpNo2)= AA(cpNo1,cpNo2)-cpValue;
                        AA(cpNo2,cpNo1)= AA(cpNo2,cpNo1)-cpValue;
                        AA(cpNo2,cpNo2)= AA(cpNo2,cpNo2)+cpValue;
                    case '地线'
                        
                    otherwise
                        msgbox("error")
                end
                
            end
            AA(1,:)=[];
            AA(:,1)=[];
            bb(1)=[];
            %c=bb.';
            results=AA\bb;
        end
        
        function setNodeNum(app,data)
            global nodeNum;
            r=str2double(data(:,2:3));
            nodeNum=int8(max(max(r)));
        end
        
        function results = getCol(app,collection)
            s=size(collection,1);
            for i=1:s
                if collection(i,1)=="电压源"
                    results=i;
                end  
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            app.UITable.ColumnName = ["名称","结点1","结点2","值1","值2"];
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "电阻"
                    app.TabGroup.SelectedTab=app.RTab;
                case "电容"
                    app.TabGroup.SelectedTab=app.CTab;
                case "电感"
                    app.TabGroup.SelectedTab=app.LTab;
                case "电压源"
                    app.TabGroup.SelectedTab=app.VTab;
                otherwise
                    app.TabGroup.SelectedTab=app.GTab;
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            d1 = getData(app,selectedButton.Text);
            app.UITable.Data=[app.UITable.Data;d1];
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            data=app.UITable.Data;
            filepath = uigetdir('*.*','请选择文件夹');
            today=datetime("today","Format","uuuu_MM_dd");
            str=[filepath,"/Circuit_Data_",string(today),".mat"];
            filename=join(str,'');
            save(filename,'data');
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            [filename,filepath]=uigetfile('*.mat','请选择文件');
            str=[filepath,filename];
            name=join(str);
            D=load(name,'data');
            da=D.data;
            app.UITable.Data=da;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            data=app.UITable.Data;
            setNodeNum(app,data);
            answer=nodeVoltage(app,data);
            tmp=[];
            for i=1:size(answer,1)
                tmp=[tmp;answer(i)+" V"];
            end
            app.TextArea2.Value=tmp;
        end

        % Value changed function: radsKnob
        function radsKnobValueChanged(app, event)
            value = app.radsKnob.Value;
            app.EditField_11.Value=floor(value);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            app.radsKnob.Value=value;
        end

        % Value changing function: radsKnob
        function radsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_11.Value=floor(changingValue);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            data=app.UITable.Data;
            posi=getCol(app,data);
            setNodeNum(app,data);
            i=1;
            for phi=1:10:359
                tmp=data;
                tmp(posi,4)=string(phi);
                answer(i)=nodeVoltage(app,tmp);
                i=i+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '电路实验';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '打开...';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '另存为...';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '添加元件';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '元件选项';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '电阻';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '电容';
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '电感';
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '电压源';
            app.Button_6 = uitogglebutton(app.ButtonGroup);
            app.Button_6.Text = '地线';
            app.TabGroup = uitabgroup(app.Panel);
            app.RTab = uitab(app.TabGroup);
            app.RTab.Title = 'R';
            app.Label_2 = uilabel(app.RTab);
            app.Label_2.Text = '，';
            app.EditField_2 = uieditfield(app.RTab, 'numeric');
            app.Label = uilabel(app.RTab);
            app.Label.Text = '端点';
            app.EditField = uieditfield(app.RTab, 'numeric');
            app.RLabel = uilabel(app.RTab);
            app.RLabel.Text = 'R =';
            app.REditField = uieditfield(app.RTab, 'numeric');
            app.Label_3 = uilabel(app.RTab);
            app.Label_3.Text = '欧';
            app.GR1RLabel = uilabel(app.RTab);
            app.GR1RLabel.Text = 'GR = 1/R';
            app.CTab = uitab(app.TabGroup);
            app.CTab.Title = 'C';
            app.EditField_3Label = uilabel(app.CTab);
            app.EditField_3Label.Text = '，';
            app.EditField_3 = uieditfield(app.CTab, 'numeric');
            app.EditField_4Label = uilabel(app.CTab);
            app.EditField_4Label.Text = '端点';
            app.EditField_4 = uieditfield(app.CTab, 'numeric');
            app.Label_4 = uilabel(app.CTab);
            app.Label_4.Text = '法';
            app.CEditFieldLabel = uilabel(app.CTab);
            app.CEditFieldLabel.Text = 'C =';
            app.CEditField = uieditfield(app.CTab, 'numeric');
            app.GCjCLabel = uilabel(app.CTab);
            app.GCjCLabel.Text = 'GC = -jωC';
            app.LTab = uitab(app.TabGroup);
            app.LTab.Title = 'L';
            app.EditField_5Label = uilabel(app.LTab);
            app.EditField_5Label.Text = '，';
            app.EditField_5 = uieditfield(app.LTab, 'numeric');
            app.EditField_6Label = uilabel(app.LTab);
            app.EditField_6Label.Text = '端点';
            app.EditField_6 = uieditfield(app.LTab, 'numeric');
            app.Label_5 = uilabel(app.LTab);
            app.Label_5.Text = '法';
            app.LEditFieldLabel = uilabel(app.LTab);
            app.LEditFieldLabel.Text = 'L =';
            app.LEditField = uieditfield(app.LTab, 'numeric');
            app.GLjLLabel = uilabel(app.LTab);
            app.GLjLLabel.Text = 'GL = -j / (ωL)';
            app.VTab = uitab(app.TabGroup);
            app.VTab.Title = 'V';
            app.EditField_7Label = uilabel(app.VTab);
            app.EditField_7Label.Text = '，';
            app.EditField_7 = uieditfield(app.VTab, 'numeric');
            app.EditField_8Label = uilabel(app.VTab);
            app.EditField_8Label.Text = '端点';
            app.EditField_8 = uieditfield(app.VTab, 'numeric');
            app.Label_6 = uilabel(app.VTab);
            app.Label_6.Text = '伏';
            app.USEditFieldLabel = uilabel(app.VTab);
            app.USEditFieldLabel.Text = 'US =';
            app.USEditField = uieditfield(app.VTab, 'numeric');
            app.Label_7 = uilabel(app.VTab);
            app.Label_7.Text = '度';
            app.Label_8 = uilabel(app.VTab);
            app.Label_8.Text = 'φ =';
            app.EditField_9 = uieditfield(app.VTab, 'numeric');
            app.USUScosjUSsinLabel = uilabel(app.VTab);
            app.USUScosjUSsinLabel.Text = 'US =US cos(φ) + jUS sin(φ)';
            app.GTab = uitab(app.TabGroup);
            app.GTab.Title = 'G';
            app.EditField_10Label = uilabel(app.GTab);
            app.EditField_10Label.Text = '端点';
            app.EditField_10 = uieditfield(app.GTab, 'numeric');
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '添加元件';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'名称'; '结点1'; '结点2'; '值1'; '值2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '计算区域';
            app.GridLayout = uigridlayout(app.Panel_2);
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '结点电压计算';
            app.TextArea2 = uitextarea(app.GridLayout);
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '频响曲线';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = 'Panel';
            app.radsKnobLabel = uilabel(app.Panel_3);
            app.radsKnobLabel.Text = 'ω / rad/s';
            app.radsKnob = uiknob(app.Panel_3, 'continuous');
            app.radsKnob.Limits = [0 1000];
            app.radsKnob.MajorTicks = [0 250 500 750 1000];
            app.radsKnob.MajorTickLabels = {'0', '250', '500', '750', '1000'};
            app.radsKnob.ValueChangedFcn = createCallbackFcn(app, @radsKnobValueChanged, true);
            app.radsKnob.ValueChangingFcn = createCallbackFcn(app, @radsKnobValueChanging, true);
            app.radsKnob.Value = 50;
            app.radsLabel = uilabel(app.Panel_3);
            app.radsLabel.Text = 'rad/s';
            app.EditField_11Label = uilabel(app.Panel_3);
            app.EditField_11Label.Text = 'ω =';
            app.EditField_11 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 50;
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'已经搞完求结点电压的程序，下一步搞求戴维宁等效电路，求等效阻抗，然后画频响曲线'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = circuit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end